classdef acmotor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ACMOTORUIFigure           matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ACMotorLabel              matlab.ui.control.Label
        PLOTDropDown              matlab.ui.control.DropDown
        PLOTButton                matlab.ui.control.Button
        PlotDetailsButton         matlab.ui.control.Button
        SupplyPanel               matlab.ui.container.Panel
        UEditFieldLabel           matlab.ui.control.Label
        UEditField                matlab.ui.control.NumericEditField
        FEditFieldLabel           matlab.ui.control.Label
        FEditField                matlab.ui.control.NumericEditField
        LoadPanel                 matlab.ui.container.Panel
        TLoadEditFieldLabel       matlab.ui.control.Label
        TLoadEditField            matlab.ui.control.NumericEditField
        TFrictionEditFieldLabel   matlab.ui.control.Label
        TFrictionEditField        matlab.ui.control.NumericEditField
        ParametersPanel           matlab.ui.container.Panel
        RsEditFieldLabel          matlab.ui.control.Label
        RsEditField               matlab.ui.control.NumericEditField
        RrEditFieldLabel          matlab.ui.control.Label
        RrEditField               matlab.ui.control.NumericEditField
        LsEditFieldLabel          matlab.ui.control.Label
        LsEditField               matlab.ui.control.NumericEditField
        LmEditFieldLabel          matlab.ui.control.Label
        LmEditField               matlab.ui.control.NumericEditField
        LrEditFieldLabel          matlab.ui.control.Label
        LrEditField               matlab.ui.control.NumericEditField
        JEditFieldLabel           matlab.ui.control.Label
        JEditField                matlab.ui.control.NumericEditField
        PolespairsEditFieldLabel  matlab.ui.control.Label
        PolespairsEditField       matlab.ui.control.NumericEditField
        PrecisionPanel            matlab.ui.container.Panel
        NEditFieldLabel           matlab.ui.control.Label
        NEditField                matlab.ui.control.NumericEditField
        SetTimesPanel             matlab.ui.container.Panel
        CouplageSliderLabel       matlab.ui.control.Label
        CouplageSlider            matlab.ui.control.Slider
        DecouplageSliderLabel     matlab.ui.control.Label
        DecouplageSlider          matlab.ui.control.Slider
        EndTimeSliderLabel        matlab.ui.control.Label
        EndTimeSlider             matlab.ui.control.Slider
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          global U Ls Lr Lm Rs Rr p J T_frottement T_contrage f N t_cpl t_dcpl b;
          Rs=0.3; %resistance statorique
          Ls=50*1e-3; %inductance statorique
          Rr=0.4; %resistance rotorique
          Lr=50*1e-3; %inductance rotorique
          Lm=47*1e-3; %inductance mutuelle
          p=2; %nombre de paires de pole
          J=1; %moment d'inertie
          T_frottement=0;
          T_contrage=0;
          f=50;
          N=2000;
          U=220;
          t_cpl=0;
          t_dcpl=0;
          b=0;
        end

        % Value changed function: JEditField
        function JEditFieldValueChanged(app, event)
            global J
            J = app.JEditField.Value;
            
        end

        % Value changed function: LrEditField
        function LrEditFieldValueChanged(app, event)
            global   Lr
            Lr = app.LrEditField.Value;
           
        end

        % Value changed function: LsEditField
        function LsEditFieldValueChanged(app, event)
            global  Ls
            Ls = app.LsEditField.Value;
            
        end

        % Value changed function: LmEditField
        function LmEditFieldValueChanged(app, event)
            global Lm
            Lm= app.LmEditField.Value;
            
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
           global N
            N = app.NEditField.Value;
           
        end

        % Value changed function: PolespairsEditField
        function PolespairsEditFieldValueChanged(app, event)
            global p
           p = app.PolespairsEditField.Value;
           
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
          app.PlotDetailsButton.Enable='on';
            
            [Y,t,wr,mn,Tem]=test();
       
          if(app.PLOTDropDown.Value=="SPEED")
          app.UIAxes.Title.String = 'Speed';
          app.UIAxes.YLabel.String = 'Wn in rad/s';
          app.UIAxes.YLabel.FontSize = 12;
          app.UIAxes.XLabel.String = 'Time in s';
          app.UIAxes.XLabel.FontSize = 12;
          plot(app.UIAxes,t,Y(:,5));
                      myworld = vrworld('ACmotor.WRL');
                      open(myworld);
                      set(myworld, 'Description', 'My first virtual world');
                      view(myworld);
                      nodes(myworld);
                      mynodes = get(myworld, 'Nodes');
                       fields(myworld.rotor);
                       for i=1:length(t)
                         myworld.rotor.rotation=[0 1 0 Y(i,5)*t(i)];
                         pause(0.015);
                       end
          end
          if(app.PLOTDropDown.Value=="STATOR CURRENT")
          app.UIAxes.Title.String = 'Stator current';
          app.UIAxes.YLabel.String = 'Is in A';
          app.UIAxes.YLabel.FontSize = 12;
          app.UIAxes.XLabel.String = 'Time in s';
          app.UIAxes.XLabel.FontSize = 12;
          plot(app.UIAxes,t,mn(1,:));
          end
           if(app.PLOTDropDown.Value=="ROTOR CURRENT")
          app.UIAxes.Title.String = 'Rotor current';
          app.UIAxes.YLabel.String = 'Ir in A';
          app.UIAxes.YLabel.FontSize = 12;
          app.UIAxes.XLabel.String = 'Time in s';
          app.UIAxes.XLabel.FontSize = 12;
          plot(app.UIAxes,t,wr(1,:));
           end
           if(app.PLOTDropDown.Value=="TORQUE")
               plot(app.UIAxes,t,Tem);
          app.UIAxes.Title.String = 'Torque';
          app.UIAxes.YLabel.String = 'T in N.m';
          app.UIAxes.YLabel.FontSize = 12;
          app.UIAxes.XLabel.String = 'Time in s';
          app.UIAxes.XLabel.FontSize = 12;
           end
        end

        % Value changed function: PLOTDropDown
        function PLOTDropDownValueChanged(app, event)
         app.PlotDetailsButton.Enable='off';
            
           
        end

        % Value changed function: RrEditField
        function RrEditFieldValueChanged(app, event)
            global Rr
           Rr = app.RrEditField.Value;
           
        end

        % Value changed function: RsEditField
        function RsEditFieldValueChanged(app, event)
            global Rs
            Rs = app.RsEditField.Value;
           
        end

        % Value changed function: TFrictionEditField
        function TFrictionEditFieldValueChanged(app, event)
            global T_frottement
             T_frottement = app.TFrictionEditField.Value;
         
        end

        % Value changed function: TLoadEditField
        function TLoadEditFieldValueChanged(app, event)
            global T_contrage
            T_contrage= app.TLoadEditField.Value;
         
        end

        % Value changed function: UEditField
        function UEditFieldValueChanged(app, event)
            global U             
            U=app.UEditField.Value;
          
        end

        % Button pushed function: PlotDetailsButton
        function PlotDetailsButtonPushed(app, event)
            [Y,t,wr,mn,Tem]=test();
          if(app.PLOTDropDown.Value=="SPEED")
          plot(t,Y(:,5));
          end
          if(app.PLOTDropDown.Value=="STATOR CURRENT")
          plot(t,mn(1,:));
          end
           if(app.PLOTDropDown.Value=="ROTOR CURRENT")
          plot(t,wr(1,:));
           end
           if(app.PLOTDropDown.Value=="TORQUE")
               plot(t,Tem);
           end
            
        end

        % Value changed function: FEditField
        function FEditFieldValueChanged(app, event)
            global f
            f = app.FEditField.Value;
           
        end

        % Value changed function: CouplageSlider
        function CouplageSliderValueChanged(app, event)
            global t_cpl;
            t_cpl = app.CouplageSlider.Value;
            
        end

        % Value changed function: DecouplageSlider
        function DecouplageSliderValueChanged(app, event)
            global t_dcpl;
            t_dcpl = app.DecouplageSlider.Value;
            
        end

        % Value changed function: EndTimeSlider
        function EndTimeSliderValueChanged(app, event)
            global b
            b = app.EndTimeSlider.Value;
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ACMOTORUIFigure = uifigure;
            app.ACMOTORUIFigure.Name = 'AC MOTOR';
            app.UIAxes = uiaxes(app.ACMOTORUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ACMotorLabel = uilabel(app.ACMOTORUIFigure);
            app.ACMotorLabel.Text = 'AC Motor ';
            app.PLOTDropDown = uidropdown(app.ACMOTORUIFigure);
            app.PLOTDropDown.Items = {'TORQUE', 'STATOR CURRENT', 'ROTOR CURRENT', 'SPEED'};
            app.PLOTDropDown.ValueChangedFcn = createCallbackFcn(app, @PLOTDropDownValueChanged, true);
            app.PLOTDropDown.Value = 'TORQUE';
            app.PLOTButton = uibutton(app.ACMOTORUIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.PlotDetailsButton = uibutton(app.ACMOTORUIFigure, 'push');
            app.PlotDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDetailsButtonPushed, true);
            app.PlotDetailsButton.Enable = 'off';
            app.PlotDetailsButton.Text = 'Plot Details';
            app.SupplyPanel = uipanel(app.ACMOTORUIFigure);
            app.SupplyPanel.Title = 'Supply';
            app.UEditFieldLabel = uilabel(app.SupplyPanel);
            app.UEditFieldLabel.Text = 'U';
            app.UEditField = uieditfield(app.SupplyPanel, 'numeric');
            app.UEditField.Limits = [0 Inf];
            app.UEditField.ValueChangedFcn = createCallbackFcn(app, @UEditFieldValueChanged, true);
            app.UEditField.Value = 220;
            app.FEditFieldLabel = uilabel(app.SupplyPanel);
            app.FEditFieldLabel.Text = 'F';
            app.FEditField = uieditfield(app.SupplyPanel, 'numeric');
            app.FEditField.Limits = [0 Inf];
            app.FEditField.ValueChangedFcn = createCallbackFcn(app, @FEditFieldValueChanged, true);
            app.FEditField.Value = 25;
            app.LoadPanel = uipanel(app.ACMOTORUIFigure);
            app.LoadPanel.Title = 'Load';
            app.TLoadEditFieldLabel = uilabel(app.LoadPanel);
            app.TLoadEditFieldLabel.Text = 'T Load';
            app.TLoadEditField = uieditfield(app.LoadPanel, 'numeric');
            app.TLoadEditField.Limits = [-Inf 0];
            app.TLoadEditField.ValueChangedFcn = createCallbackFcn(app, @TLoadEditFieldValueChanged, true);
            app.TFrictionEditFieldLabel = uilabel(app.LoadPanel);
            app.TFrictionEditFieldLabel.Text = 'T Friction';
            app.TFrictionEditField = uieditfield(app.LoadPanel, 'numeric');
            app.TFrictionEditField.Limits = [-Inf 0];
            app.TFrictionEditField.ValueChangedFcn = createCallbackFcn(app, @TFrictionEditFieldValueChanged, true);
            app.ParametersPanel = uipanel(app.ACMOTORUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.RsEditFieldLabel = uilabel(app.ParametersPanel);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.RsEditField.Limits = [1e-09 Inf];
            app.RsEditField.ValueChangedFcn = createCallbackFcn(app, @RsEditFieldValueChanged, true);
            app.RsEditField.Value = 0.3;
            app.RrEditFieldLabel = uilabel(app.ParametersPanel);
            app.RrEditFieldLabel.Text = 'Rr';
            app.RrEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.RrEditField.Limits = [1e-09 Inf];
            app.RrEditField.ValueChangedFcn = createCallbackFcn(app, @RrEditFieldValueChanged, true);
            app.RrEditField.Value = 0.4;
            app.LsEditFieldLabel = uilabel(app.ParametersPanel);
            app.LsEditFieldLabel.Text = 'Ls';
            app.LsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LsEditField.Limits = [1e-09 Inf];
            app.LsEditField.ValueChangedFcn = createCallbackFcn(app, @LsEditFieldValueChanged, true);
            app.LsEditField.Value = 0.05;
            app.LmEditFieldLabel = uilabel(app.ParametersPanel);
            app.LmEditFieldLabel.Text = 'Lm';
            app.LmEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LmEditField.Limits = [1e-09 Inf];
            app.LmEditField.ValueChangedFcn = createCallbackFcn(app, @LmEditFieldValueChanged, true);
            app.LmEditField.Value = 0.047;
            app.LrEditFieldLabel = uilabel(app.ParametersPanel);
            app.LrEditFieldLabel.Text = 'Lr';
            app.LrEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LrEditField.Limits = [1e-09 Inf];
            app.LrEditField.ValueChangedFcn = createCallbackFcn(app, @LrEditFieldValueChanged, true);
            app.LrEditField.Value = 0.05;
            app.JEditFieldLabel = uilabel(app.ParametersPanel);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.JEditField.Limits = [1e-09 Inf];
            app.JEditField.ValueChangedFcn = createCallbackFcn(app, @JEditFieldValueChanged, true);
            app.JEditField.Value = 1;
            app.PolespairsEditFieldLabel = uilabel(app.ParametersPanel);
            app.PolespairsEditFieldLabel.Text = 'Poles (pairs)';
            app.PolespairsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PolespairsEditField.Limits = [1 Inf];
            app.PolespairsEditField.ValueChangedFcn = createCallbackFcn(app, @PolespairsEditFieldValueChanged, true);
            app.PolespairsEditField.Value = 2;
            app.PrecisionPanel = uipanel(app.ACMOTORUIFigure);
            app.PrecisionPanel.Title = 'Precision';
            app.NEditFieldLabel = uilabel(app.PrecisionPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.PrecisionPanel, 'numeric');
            app.NEditField.LowerLimitInclusive = 'off';
            app.NEditField.UpperLimitInclusive = 'off';
            app.NEditField.Limits = [10 Inf];
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 2000;
            app.SetTimesPanel = uipanel(app.ACMOTORUIFigure);
            app.SetTimesPanel.Title = 'Set Times ';
            app.CouplageSliderLabel = uilabel(app.SetTimesPanel);
            app.CouplageSliderLabel.Text = 'Couplage';
            app.CouplageSlider = uislider(app.SetTimesPanel);
            app.CouplageSlider.Limits = [0 5];
            app.CouplageSlider.MajorTicks = [0 1 2 3 4 5];
            app.CouplageSlider.ValueChangedFcn = createCallbackFcn(app, @CouplageSliderValueChanged, true);
            app.DecouplageSliderLabel = uilabel(app.SetTimesPanel);
            app.DecouplageSliderLabel.Text = 'Decouplage';
            app.DecouplageSlider = uislider(app.SetTimesPanel);
            app.DecouplageSlider.Limits = [0 5];
            app.DecouplageSlider.ValueChangedFcn = createCallbackFcn(app, @DecouplageSliderValueChanged, true);
            app.EndTimeSliderLabel = uilabel(app.SetTimesPanel);
            app.EndTimeSliderLabel.Text = 'End Time';
            app.EndTimeSlider = uislider(app.SetTimesPanel);
            app.EndTimeSlider.Limits = [0 5];
            app.EndTimeSlider.ValueChangedFcn = createCallbackFcn(app, @EndTimeSliderValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = acmotor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ACMOTORUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ACMOTORUIFigure)
        end
    end
end